classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        LoadMenu                      matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        DEVICEPanel                   matlab.ui.container.Panel
        GridLayout6                   matlab.ui.container.GridLayout
        TYPEDropDown                  matlab.ui.control.DropDown
        TYPEDropDownLabel             matlab.ui.control.Label
        DEVICEDropDown                matlab.ui.control.DropDown
        MODELDropDownLabel            matlab.ui.control.Label
        PROTOCOLTIMEPanel             matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        PROTOCOLAvgDescLabel          matlab.ui.control.Label
        PROTOCOLAvgLabel              matlab.ui.control.Label
        PROTOCOLMaxDescLabel          matlab.ui.control.Label
        PROTOCOLMaxLabel              matlab.ui.control.Label
        IDPanel                       matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        STUDYEditField                matlab.ui.control.EditField
        STUDYEditFieldLabel           matlab.ui.control.Label
        RUNEditField                  matlab.ui.control.NumericEditField
        RUNEditFieldLabel             matlab.ui.control.Label
        SUBJECTEditField              matlab.ui.control.NumericEditField
        SUBJECTEditFieldLabel         matlab.ui.control.Label
        EPOCHSPanel                   matlab.ui.container.Panel
        COLORButton                   matlab.ui.control.Button
        MARKERDelButton               matlab.ui.control.Button
        MARKERAddButton               matlab.ui.control.Button
        MARKERTable                   matlab.ui.control.Table
        SESSIONINFOPanel              matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        SESSIONSTATUSLabel            matlab.ui.control.Label
        SESSSIONSTATUSDescLabel       matlab.ui.control.Label
        SESSIONDRIFTDescLabel         matlab.ui.control.Label
        SESSIONSAMPLESDescLabel       matlab.ui.control.Label
        SESSIONDRIFTLabel             matlab.ui.control.Label
        SESSIONSAMPLESLabel           matlab.ui.control.Label
        SESSIONLENGTHLabel            matlab.ui.control.Label
        SESSIONLENGTHSDescLabel       matlab.ui.control.Label
        SESSIONENDEDLabel             matlab.ui.control.Label
        SESSIONENDEDDescLabel         matlab.ui.control.Label
        SESSIONSTARTEDLabel           matlab.ui.control.Label
        SESSIONSTARTEDDescLabel       matlab.ui.control.Label
        STARTButton                   matlab.ui.control.Button
        SETTINGSPanel                 matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        DESELECTButton                matlab.ui.control.Button
        SESSIONLENGTHSEditField       matlab.ui.control.NumericEditField
        SESSIONLENGTHSEditFieldLabel  matlab.ui.control.Label
        CHANNELSLabel_3               matlab.ui.control.Label
        CHANNELSButton                matlab.ui.control.Button
        BADCHANNELSLabel              matlab.ui.control.Label
        PROTOCOLDropDown              matlab.ui.control.DropDown
        PROTOCOLLabel                 matlab.ui.control.Label
        WINDOWSIZESEditField          matlab.ui.control.NumericEditField
        WINDOWSIZESEditFieldLabel     matlab.ui.control.Label
        LSLSTREAMPanel                matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        SAMPLERATELabel               matlab.ui.control.Label
        SAMPLERATEDescLabel           matlab.ui.control.Label
        CHANNELSFOUNDLabel            matlab.ui.control.Label
        CHANNELSLabel_2               matlab.ui.control.Label
        OPENButton                    matlab.ui.control.Button
        TYPEEditField                 matlab.ui.control.EditField
        TYPELabel                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        tick uint64 = tic();
    end
    
    properties (Constant)
        idxred   uint32 = 6;
        idxgreen uint32 = 7;
        idxblue  uint32 = 8;
    end
    
    methods (Access = private)
        function onSessionStarted(app, src, ~)
            app.STARTButton.Text = "STOP";
            app.LoadMenu.Enable = false;
            app.SaveMenu.Enable = false;
            app.WINDOWSIZESEditField.Enable = false;
            app.SESSIONLENGTHSEditField.Enable = false;
            app.PROTOCOLDropDown.Enable = false;         
            app.CHANNELSButton.Enable = false;
            app.DESELECTButton.Enable = false;
            app.SUBJECTEditField.Enable = false;
            app.RUNEditField.Enable = false;
            app.STUDYEditField.Enable = false;          
            app.OPENButton.Enable = false;
            app.MARKERTable.Enable = 'off';
            app.MARKERAddButton.Enable = false;
            app.MARKERDelButton.Enable = false;
            app.COLORButton.Enable = false;
            app.SESSIONSTARTEDLabel.Text = string(...
                datetime(src.starttime,'ConvertFrom','datenum'));
            app.SESSIONENDEDLabel.Text = "-";
            app.SESSIONSTATUSLabel.Text = "Started";
        end
        
        function onSessionStopped(app, src, ~)
            app.STARTButton.Text = "START";
            app.LoadMenu.Enable = true;
            app.SaveMenu.Enable = true;
            app.WINDOWSIZESEditField.Enable = true;
            app.SESSIONLENGTHSEditField.Enable = true;
            app.PROTOCOLDropDown.Enable = true;
            app.CHANNELSButton.Enable = true;
            app.SUBJECTEditField.Enable = true;
            app.RUNEditField.Enable = true;
            app.STUDYEditField.Enable = true;
            app.OPENButton.Enable = true;
            app.MARKERTable.Enable = 'on';
            app.MARKERAddButton.Enable = true;
            if size(app.MARKERTable.Data,1) > 0
                app.MARKERDelButton.Enable = true;
                app.COLORButton.Enable = true;
            end
            app.SESSIONENDEDLabel.Text = string(...
                datetime(src.stoptime,'ConvertFrom','datenum'));
            app.SESSIONSTATUSLabel.Text = "Stopped";
            app.updateStatus();
        end

        function onChannelsSelected(app, ~, ~)
            app.updateStartButton();
        end
    end
    
    methods (Access = public)
        function updateColors(app)
            removeStyle(app.MARKERTable);
            numrows = size(app.MARKERTable.Data,1);
            for i = 1:numrows
                r = app.MARKERTable.Data(i, app.idxred);
                g = app.MARKERTable.Data(i, app.idxgreen);
                b = app.MARKERTable.Data(i, app.idxblue);
                s = uistyle();
                s.BackgroundColor = [r g b];
                s.FontColor = [r g b];
                addStyle(app.MARKERTable, s, 'cell', [i app.idxred]);
                addStyle(app.MARKERTable, s, 'cell', [i app.idxgreen]);
                addStyle(app.MARKERTable, s, 'cell', [i app.idxblue]);
            end
        end
        
        function updateStatus(app)
            global mysession
            expected = double(mysession.length) * mysession.srate;
            missing = expected - double(mysession.idx);
            drift = missing / mysession.srate;
            app.SESSIONLENGTHLabel.Text = ...
                sprintf('%.2f', mysession.length) + " s";
            app.SESSIONSAMPLESLabel.Text = ...
                string(mysession.idx) + "/" + ...
                string(mysession.datasize);
            app.SESSIONDRIFTLabel.Text = ...
                sprintf('%.2f', drift) + " s";
            if drift > 1.0 || drift < -1.0
                app.SESSIONDRIFTLabel.BackgroundColor = 'red';
            else
                app.SESSIONDRIFTLabel.BackgroundColor = 'green';
            end          
            app.PROTOCOLMaxLabel.Text = ...
                sprintf('%.2f', mysession.protocolmax) + " s";
            app.PROTOCOLAvgLabel.Text = ...
                sprintf('%.2f', mysession.protocolavg) + " s";           
            if mysession.protocolavg > (0.9*(1.0/mysession.srate))
                app.PROTOCOLAvgLabel.BackgroundColor = 'red';
            else
                app.PROTOCOLAvgLabel.BackgroundColor = 'green';
            end
        end

        function update(app)
            global mylsl
            global mysession
            if mylsl.streaming
                if toc(app.tick) >= 0.5
                    app.SAMPLERATELabel.Text = ...
                        sprintf('%.2f', mylsl.srate) + " Hz / " + ...
                        sprintf('%.2f', mylsl.sratenom) + " Hz";                
                    if mylsl.sratenom > 0.0
                        ratio = mylsl.srate/mylsl.sratenom;
                        if ratio < 0.9 || ratio > 1.1
                            app.SAMPLERATELabel.BackgroundColor = 'red';
                        else
                            app.SAMPLERATELabel.BackgroundColor = 'green';
                        end              
                    end
                    app.tick = tic();
                end
            end
            if mysession.running
                app.updateStatus();
            end
        end
        
        function updateStartButton(app)
            global myselectchannels
            global mylsl
            if ~mylsl.streaming
                app.STARTButton.Text = "CONNECT LSL FIRST";
                app.STARTButton.Enable = false;
            elseif ~myselectchannels.isok
                app.STARTButton.Text = "SELECT CHANNELS FIRST";
                app.STARTButton.Enable = false;
            else
                app.STARTButton.Text = "START";
                app.STARTButton.Enable = true;
            end
        end
        
        function updateDevices(app)
            global mydevices;
            app.DEVICEDropDown.Items = {};
            type = lower(app.TYPEDropDown.Value);
            for idx = 1:length(mydevices.(type))
                app.DEVICEDropDown.Items(idx) = ...
                    cellstr(mydevices.(type)(idx).name);
            end
        end
        
        function useDevice(app)
            global mydevices;
            global myprotocols;
            type = convertCharsToStrings(app.TYPEDropDown.Value);
            name = convertCharsToStrings(app.DEVICEDropDown.Value);
            if mydevices.select(type, name)
                % Run sanity check right after selecting
                if ~mydevices.sanityCheckSelected()
                    uialert(app.UIFigure, "Invalid device configuration: " + mydevices.selected.name, "Error");
                    return;
                end
                disp("SELECTED DEVICE: " + mydevices.selected.name + ...
                    "(" + mydevices.selected.type + ")");
                
                % Assign LSL type to UI
                app.TYPEEditField.Value = mydevices.selected.lsl.type;
                
                myprotocols.reload(mydevices.selected);
                app.PROTOCOLDropDown.Items = {};
                for idx = 1:length(myprotocols.list)
                    app.PROTOCOLDropDown.Items(idx) = ...
                        cellstr(myprotocols.list(idx).name);
                end
                app.useProtocol();
            end
        end
        
        function useProtocol(app)
            global myprotocols;
            global myselectchannels;

            name = convertCharsToStrings(app.PROTOCOLDropDown.Value);
            if myprotocols.select(name)
                disp("SELECTED PROTOCOL: " + myprotocols.selected.name)
        
                % Clear any old NF/SS selections:
                myselectchannels.selected   = [];
                myselectchannels.SSselected = [];
        
                % Rebuild both tables (Required & Selected) in one shot:
                myselectchannels.refreshAll();
        
                app.checkWindowSize();
                app.updateStartButton();
            end
        end
        
        function checkWindowSize(app)
            global myprotocols
            v = app.WINDOWSIZESEditField.Value;
            if ~isempty(myprotocols.selected)
                r = myprotocols.selected.fh.requires();
                v = min(max(v, r.window.mins), r.window.maxs);
            end
            if app.WINDOWSIZESEditField.Value ~= v
                app.WINDOWSIZESEditField.Value = v;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global mysession;
            global myselectchannels;
            app.UIFigure.Name = "NINFA v1.3.0";
            addlistener(mysession, "Started", @app.onSessionStarted);
            addlistener(mysession, "Stopped", @app.onSessionStopped);
            addlistener(myselectchannels, "Done", @app.onChannelsSelected);
            for idx = 1:length(devices.types)
                app.TYPEDropDown.Items(idx) = cellstr(devices.types(idx));
            end
            app.updateDevices();
            app.useDevice();
            app.MARKERTable.SelectionType = 'row';
            app.MARKERTable.ColumnFormat  = {'short','short','short','logical','logical','short','short','short'};
        end

        % Button pushed function: OPENButton
        function OPENButtonPushed(app, event)
            global mylsl
            global myselectchannels
            global mydevices
            if ~mylsl.streaming
                r = mylsl.open(app.TYPEEditField.Value);
                if r
                    N_lsl = double(mylsl.N);
                    N_json = double(mydevices.selected.channels_per_block);

                    % Compare JSON vs LSL N
                    if N_lsl > 0 && N_json > 0 && N_lsl ~= N_json
                        mylsl.close();
                        msgbox("Channel mismatch: device N=" + string(mydevices.selected.channels_per_block) + ...
                               " but LSL N=" + string(mylsl.N), ...
                               "Error", "error");
                        app.updateStartButton();
                        return;
                    end
                    app.tick = tic();
                    app.OPENButton.Text = "CLOSE";
                    app.TYPEDropDown.Enable = false;
                    app.DEVICEDropDown.Enable = false;
                    app.TYPEEditField.Enable = false;
                    app.CHANNELSFOUNDLabel.Text = int2str(mylsl.lslchannels);
                    app.WINDOWSIZESEditField.Enable = true;
                    app.SESSIONLENGTHSEditField.Enable = true;
                    app.PROTOCOLDropDown.Enable = true;               
                    app.SUBJECTEditField.Enable = true;
                    app.RUNEditField.Enable = true;
                    app.STUDYEditField.Enable = true;
                    app.CHANNELSButton.Enable = true;
                    app.DESELECTButton.Enable = true;
                    app.MARKERTable.Enable = 'on';
                    app.MARKERAddButton.Enable = true;
                    if size(app.MARKERTable.Data,1) > 0
                        app.MARKERDelButton.Enable = true;
                        app.COLORButton.Enable = true;
                    end
                else
                    msgbox("No LSL stream with type '" + ...
                        app.TYPEEditField.Value + ...
                        "' found", "Error", "error");
                end
            else
                mylsl.close();
                myselectchannels.close();
                app.OPENButton.Text = "OPEN";
                app.CHANNELSFOUNDLabel.Text = "-";
                app.SAMPLERATELabel.Text = "-";
                app.TYPEDropDown.Enable = true;
                app.DEVICEDropDown.Enable = true;
                app.TYPEEditField.Enable = true;
                app.WINDOWSIZESEditField.Enable = false;
                app.SESSIONLENGTHSEditField.Enable = false;
                app.PROTOCOLDropDown.Enable = false;
                app.SUBJECTEditField.Enable = false;
                app.RUNEditField.Enable = false;
                app.STUDYEditField.Enable = false;
                app.CHANNELSButton.Enable = false;
                app.DESELECTButton.Enable = false;
                app.MARKERTable.Enable = 'off';
                app.MARKERAddButton.Enable = false;
                app.MARKERDelButton.Enable = false;
                app.COLORButton.Enable = false;
                app.SAMPLERATELabel.BackgroundColor = 'none';
            end
            app.updateStartButton();
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            global mylsl;
            global mysession;
            global myselectchannels;
            global mydevices;
            global myprotocols;
        
            if ~mysession.running
                device = mydevices.selected;
        
                % Blinded flow?
                useBlindedFlow = isfield(device,'ui') && isfield(device.ui,'blind_role') && logical(device.ui.blind_role);
        
                if useBlindedFlow
                    % Decide A/B from single JSON (default vs randomize; no manual override yet)
                    A = assign_mode(device);   % struct: label, role, protocol, seed, source, reason
        
                    % Bind protocol by name; halt if missing
                    if ~myprotocols.select(char(A.protocol))
                        uialert(app.UIFigure, "Protocol not found: " + string(A.protocol), "Error");
                        return;
                    end
        
                    % Map channels from global channel_map (fixed roles: NF=long, SS=short)
                    try
                        channels   = mydevices.getLongChannelIndices();   % NF (long HbO)
                        SSchannels = mydevices.getShortChannelIndices();  % SS (short HbO)
                    catch ME
                        uialert(app.UIFigure, ...
                            "Channel mapping error: " + string(ME.message) + newline + ...
                            "Check device/LSL.", "Error");
                        return;
                    end
                    if isempty(channels)
                        uialert(app.UIFigure, "No long channels found in JSON channel_map. Check device/LSL.", "Error");
                        return;
                    end
        
                    % Stash assignment metadata on the session (saved later)
                    mysession.mode_label        = A.label;          % "A"/"B"
                    mysession.mode_role         = A.role;           % "real"/"sham"
                    mysession.mode_protocol     = A.protocol;       % e.g., "MovAvg_SS"
                    mysession.randomize_state   = isfield(device,'randomize') && logical(device.randomize);
                    mysession.default_mode_used = "A";
                    if isfield(device,'default_mode') && ~isempty(device.default_mode)
                        mysession.default_mode_used = string(device.default_mode);
                    end
                    mysession.randseed          = A.seed;           % [] unless randomized
                    if isfield(device,'name'); mysession.json_filename = string(device.name); end
        
                    protoName = string(mysession.mode_protocol);
                else
                    % Legacy / lab flow: use UI selections & dropdown
                    channels   = myselectchannels.selected;
                    SSchannels = myselectchannels.SSselected;
        
                    protoName  = erase(app.PROTOCOLDropDown.Value, ".m");
                    if ~myprotocols.select(char(protoName))
                        uialert(app.UIFigure, "Protocol not found: " + string(protoName), "Error");
                        return;
                    end
                end
        
                % Sampling rate & blocksize
                srate = mylsl.sratenom;
                if srate <= 0, srate = mylsl.srate; end
                blocksize = app.WINDOWSIZESEditField.Value * srate;
        
                % Clamp to live stream range
                maxCh = mylsl.lslchannels;
                channels   = intersect(uint32(channels),   uint32(1:maxCh), 'stable');
                SSchannels = intersect(uint32(SSchannels), uint32(1:maxCh), 'stable');
        
                % Reset stream with selected channels
                mylsl.reset(blocksize, channels, SSchannels);
        
                % Start the session
                mysession.start( ...
                    protoName, ...
                    app.SESSIONLENGTHSEditField.Value, ...
                    app.WINDOWSIZESEditField.Value, ...
                    srate, ...
                    device, ...
                    channels, ...
                    SSchannels, ...
                    app.MARKERTable.Data, ...
                    app.STUDYEditField.Value, ...
                    app.SUBJECTEditField.Value, ...
                    app.RUNEditField.Value);
        
            else
                mysession.stop();
            end
        end

        % Button pushed function: MARKERAddButton
        function MARKERAddButtonPushed(app, event)
            app.MARKERTable.Data = [app.MARKERTable.Data; [0 0 1 0 1 0 0 0]];
            app.MARKERTable.Selection = size(app.MARKERTable.Data,1);
            app.MARKERDelButton.Enable = true;
            app.COLORButton.Enable = true;
            app.updateColors();
        end

        % Button pushed function: MARKERDelButton
        function MARKERDelButtonPushed(app, event)
            if ~isempty(app.MARKERTable.Selection)                
                numrows = size(app.MARKERTable.Data,1);
                app.MARKERTable.Data(app.MARKERTable.Selection,:) = [];
                if app.MARKERTable.Selection >= numrows
                    if numrows > 1; app.MARKERTable.Selection = numrows-1;
                    else; app.MARKERTable.Selection = [];
                    end
                end
                numrows = size(app.MARKERTable.Data,1);
                if numrows <= 0
                    app.MARKERDelButton.Enable = false;
                    app.COLORButton.Enable = false;
                end
                app.updateColors();
            end
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            global mylsl;
            global myselectchannels;
            [file, path] = uigetfile("./settings/*.mat");
            figure(app.UIFigure); % focus back
            filepath = string(path) + string(file);
            if isequal(file,0) || isequal(path,0)
                return;
            end
            settings = load(filepath);           
            if isfield(settings, 'devicetype')
                app.TYPEDropDown.Value = settings.devicetype;
                app.updateDevices();
            end
            if isfield(settings, 'devicename')
                if any(strcmp(app.DEVICEDropDown.Items, settings.devicename))
                    app.DEVICEDropDown.Value = settings.devicename;
                    app.useDevice();
                else
                    msgbox("Device '" + settings.devicename + ...
                    "' was not found on this computer", "Warning", "warn");
                end
            end
            if isfield(settings, 'lsltype')
                app.TYPEEditField.Value = settings.lsltype;
            end
            if isfield(settings, 'channels')
                myselectchannels.selected = settings.channels;              
            end
            if isfield(settings, 'windowsize')
                app.WINDOWSIZESEditField.Value = settings.windowsize;
            end
            if isfield(settings, 'sessionlength')
                app.SESSIONLENGTHSEditField.Value = settings.sessionlength;        
            end
            if isfield(settings, 'protocol')
                if any(strcmp(app.PROTOCOLDropDown.Items, settings.protocol))
                    app.PROTOCOLDropDown.Value = settings.protocol;
                    app.useProtocol();
                else
                    msgbox("Protocol '" + settings.protocol + ...
                    "' was not found on this computer", "Warning", "warn");
                end
            end
            if isfield(settings, 'epochs')
                 E = settings.epochs;

                % Old schema (7 cols): [START END MARKER VISIBLE R G B]
                if size(E,2) == 7
                    vis = E(:,4);
                    rgb = E(:,5:7);
                    % New schema (8 cols): [START END MARKER TRANSFER VISIBLE R G B]
                    E = [E(:,1:3), zeros(size(E,1),1), vis, rgb];   % TRANSFER = 0 by default
                elseif size(E,2) < 8
                    % Any other undersized case -> pad to 8 columns
                    E(:, end+1:8) = 0;
                end
            
                app.MARKERTable.Data = E;
            
                if ~isempty(app.MARKERTable.Data) && mylsl.streaming
                    app.MARKERDelButton.Enable = true;
                    app.COLORButton.Enable = true;
                end
                app.updateColors();
            end
            if isfield(settings, 'study')           
                app.STUDYEditField.Value = settings.study;
            end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            global mydevices;
            global myselectchannels;
            [file, path] = uiputfile("./settings/*.mat");
            figure(app.UIFigure); % focus back
            if isequal(file,0) || isequal(path,0)
                return;
            end
            filepath = string(path) + string(file);
            settings.devicetype = mydevices.selected.type;
            settings.devicename = mydevices.selected.name;
            settings.lsltype = app.TYPEEditField.Value;
            settings.channels = myselectchannels.selected;
            settings.windowsize = app.WINDOWSIZESEditField.Value;
            settings.sessionlength = app.SESSIONLENGTHSEditField.Value;
            settings.protocol = app.PROTOCOLDropDown.Value;
            settings.epochs = app.MARKERTable.Data;
            settings.study = app.STUDYEditField.Value;
            save(filepath, '-struct','settings');
        end

        % Cell edit callback: MARKERTable
        function MARKERTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            row = indices(1,1);
            col = indices(1,2);
            if col == 3
                if newData < 1
                    app.MARKERTable.Data(row,col) = 1;
                elseif newData > 99
                    app.MARKERTable.Data(row,col) = 99;
                end
            elseif col == 1 || col == 2
                if newData < 0
                    app.MARKERTable.Data(row,col) = 0;
                end
            end
        end

        % Button pushed function: COLORButton
        function COLORButtonPushed(app, event)
            c = uisetcolor([0 0 0]);
            rows = app.MARKERTable.Selection;         
            for r = rows
                app.MARKERTable.Data(r, app.idxred)   = c(1);
                app.MARKERTable.Data(r, app.idxgreen) = c(2);
                app.MARKERTable.Data(r, app.idxblue)  = c(3);
            end           
            app.updateColors();
        end

        % Value changed function: DEVICEDropDown
        function DEVICEDropDownValueChanged(app, event)
            app.useDevice();
        end

        % Value changed function: TYPEDropDown
        function TYPEDropDownValueChanged(app, event)
            app.updateDevices();
            app.useDevice();
        end

        % Button pushed function: CHANNELSButton
        function CHANNELSButtonPushed(app, event)
            global myselectchannels;
            myselectchannels.show();
        end

        % Value changed function: PROTOCOLDropDown
        function PROTOCOLDropDownValueChanged(app, event)
            app.useProtocol();
        end

        % Value changed function: WINDOWSIZESEditField
        function WINDOWSIZESEditFieldValueChanged(app, event)
            app.checkWindowSize();
        end

        % Button pushed function: DESELECTButton
        function DESELECTButtonPushed(app, event)

            % Globals we need
            global myselectchannels;
            sc = myselectchannels;  % shorthand
            
            if isempty(sc) || ~isvalid(sc)
                uialert(app.UIFigure, 'Channel-selection module not available.', 'Error');
                return;
            end
            
            % Dialog UI
            dlg = uifigure( ...
                'Name','Deselect by Device Channels', ...
                'Position',[100 100 560 380], ...
                'Resize','off', ...
                'WindowStyle','modal');
            
            g = uigridlayout(dlg,[5 1]);
            g.RowHeight = {40, 40, '1x', 24, 40};
            g.Padding   = [10 10 10 10];
            
            % Row 1: Type radios
            row1 = uigridlayout(g,[1 4]); row1.ColumnWidth = {95, 85, 85, '1x'};
            uilabel(row1,'Text','Remove type:','HorizontalAlignment','right');
            bg = uibuttongroup(row1, 'BorderType','none'); bg.Layout.Column = [2 4];
            rHbO  = uiradiobutton(bg,'Text','HbO');  rHbO.Position  = [  5 5 60 22];
            rHbR  = uiradiobutton(bg,'Text','HbR');  rHbR.Position  = [ 70 5 60 22];
            rBoth = uiradiobutton(bg,'Text','Both'); rBoth.Position = [135 5 60 22];
            % remember last choice across opens
            persistent lastChoice; if isempty(lastChoice), lastChoice = 'HbO'; end
            switch lastChoice
                case 'HbR',  bg.SelectedObject = rHbR;
                case 'Both', bg.SelectedObject = rBoth;
                otherwise,   bg.SelectedObject = rHbO;
            end
            
            % Row 2: Input + helper
            row2 = uigridlayout(g,[2 2]);
            row2.RowHeight   = {24, 16};
            row2.ColumnWidth = {230, '1x'};
            uilabel(row2,'Text','Device channels (comma-separated):','HorizontalAlignment','right');
            txt = uieditfield(row2,'text','Placeholder','e.g., 3, 7, 12,'); txt.Layout.Column = 2;
            helper = uilabel(row2,'Text','Spaces/trailing commas are ok. Only numbers are accepted.', ...
                             'FontColor',[.4 .4 .4]);
            helper.Layout.Row = 2; helper.Layout.Column = [1 2];
            
            % Row 3: Preview table (no summary line)
            row3 = uigridlayout(g,[1 1]);
            tbl = uitable(row3,'Data',cell(0,5), ...
                'ColumnName',{'Devch','Type','LSL','Remove NF','Remove SS'}, ...
                'ColumnEditable',[false false false false false], ...
                'ColumnWidth',{60 50 60 80 80});
            
            % Row 4: Ignored tokens (italic, muted)
            ignoredLbl = uilabel(g,'Text',' ');
            ignoredLbl.FontColor = [.6 .2 .2];
            ignoredLbl.FontAngle = 'italic';
            
            % Row 5: Buttons
            row5 = uigridlayout(g,[1 3]); row5.ColumnWidth = {'1x','1x','1x'};
            btnPrev   = uibutton(row5,'Text','Preview','FontWeight','bold');
            btnApply  = uibutton(row5,'Text','Apply','FontWeight','bold','Enable','off');
            btnCancel = uibutton(row5,'Text','Cancel');
            
            % Initial state (no forced focus)
            drawnow;
            txt.Value = '';
            
            % Helpers
            function [dHbO, dHbR, ignored] = getDevchLists()
                P = sc.parseDevchInput(txt.Value);     % tolerant parse
                ignored = P.ignored;
                devchs  = double(P.devchs);
                switch bg.SelectedObject.Text
                    case 'HbO',  dHbO = devchs; dHbR = [];
                    case 'HbR',  dHbO = [];     dHbR = devchs;
                    otherwise,   dHbO = devchs; dHbR = devchs;  % Both
                end
            end
            
            function S = doPreview()
                % Parse & show ignored tokens
                [dHbO, dHbR, ignored] = getDevchLists();
                if ~isempty(ignored)
                    ignoredLbl.Text = "Ignored tokens: " + strjoin(cellstr(ignored), ', ');
                else
                    ignoredLbl.Text = " ";
                end
            
                % Build table rows and detect unmapped pairs
                rows = {};
                invalidPairs = strings(0,1);
            
                devchsAll = [dHbO(:); dHbR(:)];
                tAll      = [repmat("HbO",numel(dHbO),1); repmat("HbR",numel(dHbR),1)];
            
                % Which LSLs would be removed according to current selection?
                S = sc.simulateDeselect(uint32(dHbO), uint32(dHbR));
                nfRemove = unique([S.hbo.nf_remove, S.hbr.nf_remove]);
                ssRemove = unique([S.hbo.ss_remove, S.hbr.ss_remove]);
            
                for k = 1:numel(devchsAll)
                    d = devchsAll(k);  t = tAll(k);
                    % Try to map devch->lsl for display; collect invalid
                    lslStr = '-';
                    remNF = false; remSS = false;
                    try
                        lslIdx = sc.devch_to_lsl(d, t);
                        lslStr = num2str(lslIdx);
                        remNF  = ismember(lslIdx, nfRemove);
                        remSS  = ismember(lslIdx, ssRemove);
                    catch
                        invalidPairs(end+1) = t + "(" + string(d) + ")"; %#ok<AGROW>
                    end
                    rows(end+1, :) = {d, char(t), lslStr, logical(remNF), logical(remSS)}; %#ok<AGROW>
                end
            
                tbl.Data = rows;
            
                % If any invalid, block Apply and show a single error message
                if ~isempty(invalidPairs)
                    btnApply.Enable = 'off';
                    uialert(dlg, "Unknown device channel(s): " + strjoin(cellstr(invalidPairs), ', '), ...
                            'Not Found', 'Icon','error');
                else
                    % Enable Apply only if something will be removed
                    willRemove = (S.totals.nf_remove + S.totals.ss_remove) > 0;
                    btnApply.Enable = matlab.lang.OnOffSwitchState(willRemove);
                end
            end
            
            % ---------- Callbacks ----------
            btnPrev.ButtonPushedFcn   = @(~,~) doPreview();
            btnApply.ButtonPushedFcn  = @(~,~) onApply();
            btnCancel.ButtonPushedFcn = @(~,~) close(dlg);
            bg.SelectionChangedFcn    = @(~,~) doPreview();  % re-preview when type flips
            txt.ValueChangedFcn       = @(~,~) doPreview();
            dlg.KeyPressFcn           = @(~,e) onKey(e);
            
            function onKey(e)
                if strcmp(e.Key,'return') && ismember('control',e.Modifier)
                    if strcmp(btnApply.Enable,'on'), onApply(); end
                elseif strcmp(e.Key,'return')
                    doPreview();
                elseif strcmp(e.Key,'escape')
                    close(dlg);
                end
            end
            
            function onApply()
                lastChoice = bg.SelectedObject.Text; % remember for next open
                [dHbO, dHbR] = getDevchLists();      % re-parse once
                % Safety: if preview hasn’t been run yet, validate now
                try
                    % Validate all pairs exist (will throw on first missing)
                    for d = dHbO(:).', sc.devch_to_lsl(d,'HbO'); end
                    for d = dHbR(:).', sc.devch_to_lsl(d,'HbR'); end
                catch ME
                    uialert(dlg, ME.message, 'Not Found', 'Icon','error');
                    return;
                end
            
                % Apply deselection
                R = sc.applyDeselect(uint32(dHbO), uint32(dHbR));
                app.updateStartButton();   % reflect in main UI
                close(dlg);
            
                % Confirmation
                parts = string([]);
                if ~isempty(dHbO), parts(end+1) = "HbO(" + strjoin(string(dHbO),',') + ")"; end
                if ~isempty(dHbR), parts(end+1) = "HbR(" + strjoin(string(dHbR),',') + ")"; end
                if isempty(parts), parts = "nothing"; end
                uialert(app.UIFigure, ...
                    sprintf('Removed %s. NF %d→%d, SS %d→%d.', ...
                        strjoin(cellstr(parts), '; '), R.nf_before, R.nf_after, R.ss_before, R.ss_after), ...
                    'Deselection Complete');
            end
            
            % First preview on open (empty input)
            doPreview();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.LSLSTREAMPanel = uipanel(app.UIFigure);
            app.LSLSTREAMPanel.Title = 'LSL STREAM';
            app.GridLayout = uigridlayout(app.LSLSTREAMPanel);
            app.TYPELabel = uilabel(app.GridLayout);
            app.TYPELabel.Text = 'TYPE';
            app.TYPEEditField = uieditfield(app.GridLayout, 'text');
            app.OPENButton = uibutton(app.GridLayout, 'push');
            app.OPENButton.ButtonPushedFcn = createCallbackFcn(app, @OPENButtonPushed, true);
            app.OPENButton.Text = 'OPEN';
            app.CHANNELSLabel_2 = uilabel(app.GridLayout);
            app.CHANNELSLabel_2.Text = 'CHANNELS';
            app.CHANNELSFOUNDLabel = uilabel(app.GridLayout);
            app.CHANNELSFOUNDLabel.Text = '-';
            app.SAMPLERATEDescLabel = uilabel(app.GridLayout);
            app.SAMPLERATEDescLabel.Text = 'SAMPLE RATE';
            app.SAMPLERATELabel = uilabel(app.GridLayout);
            app.SAMPLERATELabel.Text = '-';
            app.SETTINGSPanel = uipanel(app.UIFigure);
            app.SETTINGSPanel.Title = 'SETTINGS';
            app.GridLayout2 = uigridlayout(app.SETTINGSPanel);
            app.WINDOWSIZESEditFieldLabel = uilabel(app.GridLayout2);
            app.WINDOWSIZESEditFieldLabel.Text = 'WINDOW SIZE (S)';
            app.WINDOWSIZESEditField = uieditfield(app.GridLayout2, 'numeric');
            app.WINDOWSIZESEditField.Limits = [0 3600];
            app.WINDOWSIZESEditField.ValueChangedFcn = createCallbackFcn(app, @WINDOWSIZESEditFieldValueChanged, true);
            app.WINDOWSIZESEditField.Enable = 'off';
            app.WINDOWSIZESEditField.Value = 1;
            app.PROTOCOLLabel = uilabel(app.GridLayout2);
            app.PROTOCOLLabel.Text = 'PROTOCOL';
            app.PROTOCOLDropDown = uidropdown(app.GridLayout2);
            app.PROTOCOLDropDown.Items = {};
            app.PROTOCOLDropDown.ValueChangedFcn = createCallbackFcn(app, @PROTOCOLDropDownValueChanged, true);
            app.PROTOCOLDropDown.Enable = 'off';
            app.PROTOCOLDropDown.Tooltip = {'Select the Matlab file that should be executed on each window calculating the next feedback. '};
            app.PROTOCOLDropDown.Value = {};
            app.BADCHANNELSLabel = uilabel(app.GridLayout2);
            app.BADCHANNELSLabel.Text = 'BAD CHANNELS';
            app.CHANNELSButton = uibutton(app.GridLayout2, 'push');
            app.CHANNELSButton.ButtonPushedFcn = createCallbackFcn(app, @CHANNELSButtonPushed, true);
            app.CHANNELSButton.Enable = 'off';
            app.CHANNELSButton.Text = 'SELECT';
            app.CHANNELSLabel_3 = uilabel(app.GridLayout2);
            app.CHANNELSLabel_3.Text = 'CHANNELS';
            app.SESSIONLENGTHSEditFieldLabel = uilabel(app.GridLayout2);
            app.SESSIONLENGTHSEditFieldLabel.Text = 'SESSION LENGTH (S)';
            app.SESSIONLENGTHSEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SESSIONLENGTHSEditField.Limits = [0 3600];
            app.SESSIONLENGTHSEditField.Enable = 'off';
            app.SESSIONLENGTHSEditField.Value = 10;
            app.DESELECTButton = uibutton(app.GridLayout2, 'push');
            app.DESELECTButton.ButtonPushedFcn = createCallbackFcn(app, @DESELECTButtonPushed, true);
            app.DESELECTButton.Enable = 'off';
            app.DESELECTButton.Text = 'DESELECT';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Enable = 'off';
            app.STARTButton.Tooltip = {'Start or stop session'};
            app.STARTButton.Text = 'START';
            app.SESSIONINFOPanel = uipanel(app.UIFigure);
            app.SESSIONINFOPanel.Title = 'SESSION INFO';
            app.GridLayout3 = uigridlayout(app.SESSIONINFOPanel);
            app.SESSIONSTARTEDDescLabel = uilabel(app.GridLayout3);
            app.SESSIONSTARTEDDescLabel.Text = 'STARTED:';
            app.SESSIONSTARTEDLabel = uilabel(app.GridLayout3);
            app.SESSIONSTARTEDLabel.Text = '-';
            app.SESSIONENDEDDescLabel = uilabel(app.GridLayout3);
            app.SESSIONENDEDDescLabel.Text = 'ENDED:';
            app.SESSIONENDEDLabel = uilabel(app.GridLayout3);
            app.SESSIONENDEDLabel.Text = '-';
            app.SESSIONLENGTHSDescLabel = uilabel(app.GridLayout3);
            app.SESSIONLENGTHSDescLabel.Text = 'DURATION:';
            app.SESSIONLENGTHLabel = uilabel(app.GridLayout3);
            app.SESSIONLENGTHLabel.Text = '-';
            app.SESSIONSAMPLESLabel = uilabel(app.GridLayout3);
            app.SESSIONSAMPLESLabel.Text = '-';
            app.SESSIONDRIFTLabel = uilabel(app.GridLayout3);
            app.SESSIONDRIFTLabel.Text = '-';
            app.SESSIONSAMPLESDescLabel = uilabel(app.GridLayout3);
            app.SESSIONSAMPLESDescLabel.Text = 'SAMPLES:';
            app.SESSIONDRIFTDescLabel = uilabel(app.GridLayout3);
            app.SESSIONDRIFTDescLabel.Text = 'DELAY';
            app.SESSSIONSTATUSDescLabel = uilabel(app.GridLayout3);
            app.SESSSIONSTATUSDescLabel.Text = 'STATUS:';
            app.SESSIONSTATUSLabel = uilabel(app.GridLayout3);
            app.SESSIONSTATUSLabel.Text = 'Stopped';
            app.EPOCHSPanel = uipanel(app.UIFigure);
            app.EPOCHSPanel.Title = 'EPOCHS';
            app.MARKERTable = uitable(app.EPOCHSPanel);
            app.MARKERTable.ColumnName = {'START (S)'; 'END (S)'; 'MARKER'; 'TRANSFER'; 'VISIBLE'; 'R'; 'G'; 'B'};
            app.MARKERTable.RowName = {};
            app.MARKERTable.ColumnEditable = [true true true true true false false false];
            app.MARKERTable.CellEditCallback = createCallbackFcn(app, @MARKERTableCellEdit, true);
            app.MARKERTable.Tooltip = {'Define markers (a value between 1 and 99) for epochs here. An epoch is defined by its start and end time. A trigger will be sent at the beginning of each epoch.'};
            app.MARKERTable.Enable = 'off';
            app.MARKERAddButton = uibutton(app.EPOCHSPanel, 'push');
            app.MARKERAddButton.ButtonPushedFcn = createCallbackFcn(app, @MARKERAddButtonPushed, true);
            app.MARKERAddButton.Enable = 'off';
            app.MARKERAddButton.Tooltip = {'Add an epoch'};
            app.MARKERAddButton.Text = '+';
            app.MARKERDelButton = uibutton(app.EPOCHSPanel, 'push');
            app.MARKERDelButton.ButtonPushedFcn = createCallbackFcn(app, @MARKERDelButtonPushed, true);
            app.MARKERDelButton.Enable = 'off';
            app.MARKERDelButton.Tooltip = {'Remove last or selected epochs'};
            app.MARKERDelButton.Text = '-';
            app.COLORButton = uibutton(app.EPOCHSPanel, 'push');
            app.COLORButton.ButtonPushedFcn = createCallbackFcn(app, @COLORButtonPushed, true);
            app.COLORButton.Enable = 'off';
            app.COLORButton.Tooltip = {'Select color for selected epochs'};
            app.COLORButton.Text = 'COLOR';
            app.IDPanel = uipanel(app.UIFigure);
            app.IDPanel.Title = 'ID';
            app.GridLayout4 = uigridlayout(app.IDPanel);
            app.SUBJECTEditFieldLabel = uilabel(app.GridLayout4);
            app.SUBJECTEditFieldLabel.Text = 'SUBJECT';
            app.SUBJECTEditField = uieditfield(app.GridLayout4, 'numeric');
            app.SUBJECTEditField.Limits = [1 10000];
            app.SUBJECTEditField.Enable = 'off';
            app.SUBJECTEditField.Value = 1;
            app.RUNEditFieldLabel = uilabel(app.GridLayout4);
            app.RUNEditFieldLabel.Text = 'RUN';
            app.RUNEditField = uieditfield(app.GridLayout4, 'numeric');
            app.RUNEditField.Limits = [1 1000];
            app.RUNEditField.Enable = 'off';
            app.RUNEditField.Value = 1;
            app.STUDYEditFieldLabel = uilabel(app.GridLayout4);
            app.STUDYEditFieldLabel.Text = 'STUDY';
            app.STUDYEditField = uieditfield(app.GridLayout4, 'text');
            app.STUDYEditField.Enable = 'off';
            app.STUDYEditField.Tooltip = {''};
            app.PROTOCOLTIMEPanel = uipanel(app.UIFigure);
            app.PROTOCOLTIMEPanel.Title = 'PROTOCOL TIME';
            app.GridLayout5 = uigridlayout(app.PROTOCOLTIMEPanel);
            app.PROTOCOLMaxLabel = uilabel(app.GridLayout5);
            app.PROTOCOLMaxLabel.Text = '-';
            app.PROTOCOLMaxDescLabel = uilabel(app.GridLayout5);
            app.PROTOCOLMaxDescLabel.Text = 'MAX:';
            app.PROTOCOLAvgLabel = uilabel(app.GridLayout5);
            app.PROTOCOLAvgLabel.Text = '-';
            app.PROTOCOLAvgDescLabel = uilabel(app.GridLayout5);
            app.PROTOCOLAvgDescLabel.Text = 'AVG:';
            app.DEVICEPanel = uipanel(app.UIFigure);
            app.DEVICEPanel.Title = 'DEVICE';
            app.GridLayout6 = uigridlayout(app.DEVICEPanel);
            app.MODELDropDownLabel = uilabel(app.GridLayout6);
            app.MODELDropDownLabel.Text = 'MODEL';
            app.DEVICEDropDown = uidropdown(app.GridLayout6);
            app.DEVICEDropDown.Items = {};
            app.DEVICEDropDown.ValueChangedFcn = createCallbackFcn(app, @DEVICEDropDownValueChanged, true);
            app.DEVICEDropDown.Value = {};
            app.TYPEDropDownLabel = uilabel(app.GridLayout6);
            app.TYPEDropDownLabel.Text = 'TYPE';
            app.TYPEDropDown = uidropdown(app.GridLayout6);
            app.TYPEDropDown.Items = {};
            app.TYPEDropDown.ValueChangedFcn = createCallbackFcn(app, @TYPEDropDownValueChanged, true);
            app.TYPEDropDown.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end